classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BankAngleEditField       matlab.ui.control.NumericEditField
        BankAngleEditFieldLabel  matlab.ui.control.Label
        VelocityEditField        matlab.ui.control.NumericEditField
        VelocityEditFieldLabel   matlab.ui.control.Label
        AnimateButton            matlab.ui.control.Button
        TestingifthecodeworksandisinteractableLabel  matlab.ui.control.Label
        RadiusSlider             matlab.ui.control.Slider
        RadiusSliderLabel        matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RadiusSlider
        function RadiusSliderValueChanged(app, event)
            global x y;%using global here to allow us to access the x,y later on when the button is pushed
            r = app.RadiusSlider.Value;%takes the radius value
            L = 50;%our fixed arc length
            x = zeros(1,100);y = linspace(-10,0); %additional padding to the beginning of the graph to have a "straight road"
            X = 0.01;
            increment = length(x);
            while ~(y(increment)>= r*sin(L/r)) || ~(X>=r-r*cos(L/r))
                x = [x, X];
                y = [y, sqrt(2*r*X-X^2)];
                increment = increment + 1;
                X = X + 0.01;
            end
            %the while loop section above basically fills in the x/y
            %vectors with the values for the graph of that curve/road
            p = plot(app.UIAxes,x,y,x,y);

            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            %stuff added by Muaadh to create the actual road graph
        end

        % Callback function
        function ArcLengthSliderValueChanged(app, event)

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            global x y;
            p = plot(app.UIAxes,x,y,x,y);
            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            hold(app.UIAxes);
            p = plot(app.UIAxes,x(1),y(1),'o','MarkerFaceColor','red');
            hold(app.UIAxes);

            %axis manual

            v = app.VelocityEditField.Value;
            bankAng = app.BankAngleEditField.Value;
            m=0;
            r = app.RadiusSlider.Value;
            [m,coeffric] =INPUT();
            [g,f]= Gforcefriction(v,r,m,bankAng);
            
            for k = 2:15:length(x)
                p.XData = x(k);
                p.YData = y(k);
                drawnow
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 50];
            app.UIAxes.YLim = [-5 50];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Friction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'G Force')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RadiusSliderLabel = uilabel(app.UIFigure);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.UIFigure);
            app.RadiusSlider.Limits = [16 200];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusSliderValueChanged, true);
            app.RadiusSlider.Value = 16;
            app.TestingifthecodeworksandisinteractableLabel = uilabel(app.UIFigure);
            app.TestingifthecodeworksandisinteractableLabel.Text = 'Testing if the code works and is interactable';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.Value = 5;
            app.BankAngleEditFieldLabel = uilabel(app.UIFigure);
            app.BankAngleEditFieldLabel.Text = 'Bank Angle';
            app.BankAngleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end