classdef app_muaadh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_4                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RadiusofCurvaturemSpinner       matlab.ui.control.Spinner
        RadiusofCurvaturemSpinnerLabel  matlab.ui.control.Label
        Button_10                       matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        TyreTypesDropDown               matlab.ui.control.DropDown
        TyreTypesDropDownLabel          matlab.ui.control.Label
        MoreInfoaboutProgramButton      matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        MaximumBankAngleEditField       matlab.ui.control.NumericEditField
        MaximumBankAngleLabel           matlab.ui.control.Label
        VelocitymsEditField             matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel        matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        GForceandFrictioncalculatoronbankedroadsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            global x y;
            pr = plot(app.UIAxes,x,y,x,y);
            pr(1).LineWidth = 10;
            pr(1).Color = 'black';
            pr(2).LineStyle = "--";
            pr(2).Color = 'white';
            pr(2).LineWidth = 1;
            hold(app.UIAxes);
            p = plot(app.UIAxes,x(1),y(1),'o','MarkerFaceColor','red');
            hold(app.UIAxes);
            %Redraws the road to avoid issues


            %defines variables for the app/axes parts
            ax = app.UIAxes;
            ax2 = app.UIAxes2;
            ax3 = app.UIAxes3;
            cla(ax2);
            cla(ax3);
            v = app.VelocitymsEditField.Value;
            bankAng = app.MaximumBankAngleEditField.Value;
            m=app.MasskgEditField.Value;
            r = app.RadiusofCurvaturemSpinner.Value;
            DropDown = app.TyreTypesDropDown.Value;

            % compares the dropdown values and assigns the correct value of
            % friction
            if strcmp(DropDown, 'Standard')
                TyreFriction = 0.7;
            elseif strcmp(DropDown,'Racing')
                TyreFriction = 0.9;
            end            
            G = [];
            F = [];
            %due to our assumption of 50m road length, the min radius we
            %can use is 50/π, as we are technically drawing a semi circle
            %For convenience sake, we change any radius <16 to 16
            if r<16
                r = 16;
            end
            
            %computes max safe velocity
            vmax = vmaxcalc(r,bankAng,TyreFriction);

            %displays it
            app.Label_4.Text = ['Maximum safe velocity = ',num2str(vmax)];
            if v > vmax

                %when the car is way too fast, and slips
                msgbox(['Velocity of the vehicle is greater than the maximum ' ...
                'Velocity possible before the car loses control'],'Warning', 'error' )
                %displays image
                app.Image.Visible = "on";
                app.UIAxes.Visible = "off";
                
                %plays audio
                [data, Fs] = audioread('CrashF.mp3');
                soundsc(data,Fs);                                
                app.AnimateButton.Enable = 'off';
                pause(4)
                app.AnimateButton.Enable = 'on';

                
            else
                %if the speed is safe
                app.Image.Visible = "off";  

                app.UIAxes.Visible = "on";
                if bankAng > 0

                    %uses GForcefriction function to compute the values
                    for i = 0:(bankAng/100):bankAng

                        [g,f]=Gforcefriction(v,r,m,i,TyreFriction);

                        G=[G,g];
                        F=[F,f];

                    end

                    %graphs the Friction and G Force
                    hold(app.UIAxes3)
                    hold(app.UIAxes2)
                    i = 0:(bankAng/100):bankAng;
                    plot(app.UIAxes3,i,G,'G')
                    plot(app.UIAxes2,i,F,'R')
                    i = bankAng:(bankAng/100):2*bankAng;
                    plot(app.UIAxes3,i,fliplr(G),'G')
                    plot(app.UIAxes2,i,fliplr(F),'R')

                    xlabel(app.UIAxes2, 'Bank Angle(°)')
                    xlabel(app.UIAxes3, 'Bank Angle(°)')
                    ax2.XTick = [0, bankAng/2, bankAng, 1.5*bankAng, 2*bankAng];
                    ax3.XTick = [0, bankAng/2, bankAng, 1.5*bankAng, 2*bankAng];
                    ax2.XTickLabel = {0, bankAng/2, bankAng, bankAng/2, 0};
                    ax3.XTickLabel = {0, bankAng/2, bankAng, bankAng/2, 0};
                    hold(app.UIAxes3)
                    hold(app.UIAxes2)
                    axis(ax2)
                    axis(ax3)
                elseif bankAng == 0
                    [g,f]=Gforcefriction(v,r,m,0,TyreFriction);
                    G = ones(1,10)*g;
                    F = ones(1,10)*f;
                    plot(app.UIAxes3,1:10,G,'G')
                    plot(app.UIAxes2,1:10,F,'R')
                    xlabel(app.UIAxes2, 'Distance travelled on the road(m)')
                    xlabel(app.UIAxes3, 'Distance travelled on the road(m)')
                    
                    ax2.XTick = [0, 2.5, 5, 7.5, 10];
                    ax3.XTick = [0, 2.5, 5, 7.5, 10];
                    ax2.XTickLabel = {0, 12.5, 25, 37.5, 50};
                    ax3.XTickLabel = {0, 12.5, 25, 37.5, 50};
                end
                app.Label_2.Text = ['Max Friction = ', num2str(max(F)),'N'];
                app.Label_3.Text = ['Max G-Force = ', num2str(max(G)),'g'];

                app.AnimateButton.Enable = 'off';
                %prevents user from clicking while animation is ongoing
                
                %animating the red dot
                for k = 2:10:length(x)
                    p.XData = x(k);
                    p.YData = y(k);
                    refreshdata(p)
                    pause(0.001)
                end
                
                app.AnimateButton.Text='Re-Animate';
                app.AnimateButton.Enable = 'on';
            end

        end

        % Button pushed function: MoreInfoaboutProgramButton
        function MoreInfoaboutProgramButtonPushed(app, event)
            DIAG(['This is the ENGG100 Project for the Spring 2023 designed by' char newline '1. Fouz Reda Kammukutty(7868558)' ...
                char newline '2. Muaadh Shihabdeen(7926534)' char newline '3. Manu Augustine Sherry(7865065)' char newline '4. Ahmed Omer(8044533)' char newline '5. Mazen Fakhry(7732715) ' char newline 'This program aims to determine the g-force experienced by a car with uniform velocity through a turn, and also calculating the friction between the road and the tyre, and determining whether or not the car will slip ' char newline 'The entire project is accessible on the github page'],'About this Program')
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            DIAG('Allows the user to change the radius of curvature of the turn, I.E., how much the curve bends. We assume a fixed length of road at 50m, as such, the min radius of curvature is 50/π , which is approximately 16m','Help')
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            DIAG(['Allows the user to change the velocity to their hearts content. ' ...
                'The Program will alert the user if the velocity is too high and overcomes friction and slips, increasing likelihood of a crash'],'Help')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            DIAG('Approximate Mass of the vehicle','Help:Mass')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            DIAG('Bank Angle is the angle of the road with respect to the ground, usually increasing towards the middle of the curve, and levels off near the entry/exit of a turn','Help')
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            DIAG(['Animate button starts the computation and the dot ' ...
                'animation, and filling in the G-Force and Friction Graphs'], ...
                'Help')
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            DIAG(['A simple representation of the motion of the car on the ' ...
                'curve. The dot represents the vehicle with its motion on the road'],'Help')
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            DIAG('Graphs the friction with respect to the bank angle or the position on the road if bank angle=0','Help')
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            DIAG('Graphs the g-force with respect to the bank angle or the position on the road if bank angle=0','Help')
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            DIAG('Lets the user pick between 2 different tyre types, Standard(μ = 0.7) vs Racing(μ = 0.9)','Help')
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            web('https://github.com/FouzR/ENGG100_Project')
        end

        % Value changed function: RadiusofCurvaturemSpinner
        function RadiusofCurvaturemSpinnerValueChanged(app, event)
            global x y;%using global here to allow us to access the x,y later on when the button is pushed
            r = app.RadiusofCurvaturemSpinner.Value;%takes the radius value
            if r<16
                r = 16;
            end
            L = 50;%our fixed arc length
            x = zeros(1,100);y = linspace(-10,0); %additional padding to the beginning of the graph to have a "straight road"
            X = 0.01;
            increment = length(x);
            while ~(y(increment)>= r*sin(L/r)) || ~(X>=r-r*cos(L/r))
                x = [x, X];
                y = [y, sqrt(2*r*X-X^2)];
                increment = increment + 1;
                X = X + 0.01;
            end
            %the while loop section above basically fills in the x/y
            %vectors with the values for the graph of that curve/road
            p = plot(app.UIAxes,x,y,x,y);

            p(1).LineWidth = 10;
            p(1).Color = 'black';
            p(2).LineStyle = "--";
            p(2).Color = 'white';
            p(2).LineWidth = 1;
            %stuff added by Muaadh to create the actual road graph
        end

        % Value changed function: VelocitymsEditField
        function VelocitymsEditFieldValueChanged(app, event)
            value = app.VelocitymsEditField.Value;
            km = 3.6*value;
            app.Label.Text = [num2str(km), ' km/h'];
            %Updates a small text box with the km/h value when v(m/s) is
            %updated
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Friction')
            xlabel(app.UIAxes2, 'Bank Angle(°)')
            ylabel(app.UIAxes2, 'Friction Force(N)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'G Force')
            xlabel(app.UIAxes3, 'Bank Angle(°)')
            ylabel(app.UIAxes3, 'G-Force(g)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Representation of the vehicle(Top Down)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 50];
            app.UIAxes.YLim = [-5 50];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.GForceandFrictioncalculatoronbankedroadsLabel = uilabel(app.UIFigure);
            app.GForceandFrictioncalculatoronbankedroadsLabel.Text = 'G-Force and Friction calculator on banked roads';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitymsEditFieldValueChanged, true);
            app.MaximumBankAngleLabel = uilabel(app.UIFigure);
            app.MaximumBankAngleLabel.Text = 'Maximum Bank Angle ( ° )';
            app.MaximumBankAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 900;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '?';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '?';
            app.MoreInfoaboutProgramButton = uibutton(app.UIFigure, 'push');
            app.MoreInfoaboutProgramButton.ButtonPushedFcn = createCallbackFcn(app, @MoreInfoaboutProgramButtonPushed, true);
            app.MoreInfoaboutProgramButton.Text = 'More Info about Program';
            app.TyreTypesDropDownLabel = uilabel(app.UIFigure);
            app.TyreTypesDropDownLabel.Text = 'Tyre Types';
            app.TyreTypesDropDown = uidropdown(app.UIFigure);
            app.TyreTypesDropDown.Items = {'Standard', 'Racing'};
            app.TyreTypesDropDown.Value = 'Standard';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '?';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = fullfile(pathToMLAPP, 'git.png');
            app.Button_10.Text = '';
            app.RadiusofCurvaturemSpinnerLabel = uilabel(app.UIFigure);
            app.RadiusofCurvaturemSpinnerLabel.Text = 'Radius of Curvature(m)';
            app.RadiusofCurvaturemSpinner = uispinner(app.UIFigure);
            app.RadiusofCurvaturemSpinner.Limits = [1 500];
            app.RadiusofCurvaturemSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiusofCurvaturemSpinnerValueChanged, true);
            app.RadiusofCurvaturemSpinner.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Crashed.jpg');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_muaadh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end